classdef MixerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SimpleEqualizerLabel           matlab.ui.control.Label
        HighGainKnob                   matlab.ui.control.Knob
        HighGainKnobLabel              matlab.ui.control.Label
        MidGainKnob                    matlab.ui.control.Knob
        MidGainKnobLabel               matlab.ui.control.Label
        LowGainKnob                    matlab.ui.control.Knob
        LowGainKnobLabel               matlab.ui.control.Label
        LowCenterFrequencyKnob         matlab.ui.control.Knob
        LowCenterFrequencyKnobLabel    matlab.ui.control.Label
        HighCenterFrequencyKnob        matlab.ui.control.Knob
        HighCenterFrequencyKnobLabel   matlab.ui.control.Label
        MidCenterFrequencyKnob         matlab.ui.control.Knob
        MidCenterFrequencyKnobLabel    matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        PeakingEQGainAttenuationLabel  matlab.ui.control.Label
        PeakingEQMidFrequancyLabel     matlab.ui.control.Label
        VolumeLabel                    matlab.ui.control.Label
        NotchMidFrequancyLabel         matlab.ui.control.Label
        HighPassFilterFcLabel          matlab.ui.control.Label
        LowPassFilterFcLabel           matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Knob_10                        matlab.ui.control.Knob
        InputGainKnob                  matlab.ui.control.Knob
        InputGainKnobLabel             matlab.ui.control.Label
        Device1DropDown                matlab.ui.control.DropDown
        Device1DropDownLabel           matlab.ui.control.Label
        DelayAlphaLabel                matlab.ui.control.Label
        DelayTimeLabel                 matlab.ui.control.Label
        ReverbAlphaLabel               matlab.ui.control.Label
        SampleRateTextAreaLabel        matlab.ui.control.Label
        ReverbDelayTimeLabel           matlab.ui.control.Label
        SampleRateTextArea             matlab.ui.control.TextArea
        Knob                           matlab.ui.control.Knob
        PowerOnSwitch                  matlab.ui.control.RockerSwitch
        PowerOnSwitchLabel             matlab.ui.control.Label
        OutputDeviceDropDown           matlab.ui.control.DropDown
        OutputDeviceDropDownLabel      matlab.ui.control.Label
        FiltersEffectsListBox          matlab.ui.control.ListBox
        FiltersEffectsListBoxLabel     matlab.ui.control.Label
        Knob_9                         matlab.ui.control.Knob
        Knob_8                         matlab.ui.control.Knob
        Knob_7                         matlab.ui.control.Knob
        Knob_6                         matlab.ui.control.Knob
        Knob_4                         matlab.ui.control.Knob
        Knob_3                         matlab.ui.control.Knob
        Knob_2                         matlab.ui.control.Knob
        DeviceDropDown_3Label_2        matlab.ui.control.Label
        DriverDropDown                 matlab.ui.control.DropDown
        Slider_2                       matlab.ui.control.Slider
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        input1 % Audio Inputs
        input2
        input3
        output % Audio Output
        deviceReader1 % First Audio Device Input
        deviceReader2 % Second Audio Device Input
        deviceReader3 % Third Audio Device Input
        devicewriter % output device set
        ECHO  
        PHASER
        FLANGER
        DELAY
        LOWPASS
        HIGHPASS
        PEAKINGEQ
        NOTCH
        EQ
        Gain
        x_save = []
    end
    
    properties (Access = public)
         nChannels
         filters
         volume
         driver
         devices 
         outputs
         start
         fs % Sample Rate
         Spectrum
         buffer
         Knob1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize Filters
            app.filters = "Off";
            app.ECHO = Reverb();
            app.PHASER = audiopluginexample.Phaser();
            app.FLANGER = audiopluginexample.Flanger();
            app.DELAY = Delay();            
            app.LOWPASS = LowPass();
            app.HIGHPASS = HighPass();
            app.PEAKINGEQ = PeakingEQ();
            app.NOTCH = Notch();
            app.EQ = Equalizer();
            app.Gain = 1;

            app.volume = app.Slider_2.Value;
            app.UIAxes.XLim = [0 1024];
            app.UIAxes.YLim = [-.25 .25];
            % Number of input channels
            app.fs = 44100;
            app.nChannels = 2;
            app.driver = app.DriverDropDown.Value;
            deviceReader = audioDeviceReader("Driver",app.driver);
            app.devices = getAudioDevices(deviceReader);

            % Set all of the drop down windows with desktop audio devices
%             app.Device2DropDown.Items = app.devices;
            app.Device1DropDown.Items = app.devices;
%             app.Device3DropDown.Items = app.devices;

            % Set audio device 1 to default driver and device
            app.deviceReader1 = audioDeviceReader("NumChannels", ...
                app.nChannels,"Driver",app.driver, ...
                'Device',app.Device1DropDown.Value, 'SampleRate',app.fs);

%             % Set audio device 2 to default driver and device
%             app.deviceReader2 = audioDeviceReader("NumChannels", ...
%                 app.nChannels,"Driver",app.driver, ...
%                 'Device',app.Device2DropDown.Value, 'SampleRate',app.fs);

%             % Set audio device 3 to default driver and device
%             app.deviceReader3 = audioDeviceReader("NumChannels", ...
%                 app.nChannels,"Driver",app.driver, ...
%                 'Device',app.Device3DropDown.Value, 'SampleRate',app.fs);
            deviceWriter = audioDeviceWriter("Driver",app.driver);

            % Get audio outputs and set audio writer
            app.outputs = getAudioDevices(deviceWriter);
            app.OutputDeviceDropDown.Items = app.outputs;
            app.devicewriter = audioDeviceWriter("Driver",app.driver, ...
                "Device",app.OutputDeviceDropDown.Value,'SampleRate',app.fs,'SupportVariableSizeInput',true);
            
            % Get sample Rate
            app.fs = get(app.devicewriter,'SampleRate');
            app.buffer = get(app.deviceReader1,'SamplesPerFrame');

            % Initialize spectrogram 
            app.Spectrum = dsp.SpectrumAnalyzer('SampleRate',app.fs, ...
                'PlotAsTwoSidedSpectrum', ...
            false,'FrequencyScale','Log');
            
            % Display sample rate
            app.SampleRateTextArea.Value = string(app.fs) + "- " + string(app.buffer);   
            
            
        end

        % Value changed function: DriverDropDown
        function DriverDropDownValueChanged(app, event)
            app.driver = app.DriverDropDown.Value;
            deviceReader = audioDeviceReader("Driver",app.driver);
            app.devices = getAudioDevices(deviceReader);

            % Change Device 1
            app.Device1DropDown.Items = app.devices;
            app.deviceReader1 = audioDeviceReader("NumChannels", ...
                app.nChannels,"Driver",app.driver, ...
        'Device',app.Device1DropDown.Value, 'SampleRate',app.fs);

%             % Change Device 2
%             app.Device2DropDown.Items = app.devices;
%             app.deviceReader2 = audioDeviceReader("NumChannels", ...
%                 app.nChannels,"Driver",app.driver, ...
%         'Device',app.Device2DropDown.Value, 'SampleRate',app.fs); 
% 
%             % Change Decice 3
%             app.Device3DropDown.Items = app.devices;
%             app.deviceReader2 = audioDeviceReader("NumChannels", ...
%                 app.nChannels,"Driver",app.driver, ...
%         'Device',app.Device3DropDown.Value, 'SampleRate',app.fs);

            % Change Device Writer
            deviceWriter = audioDeviceWriter("Driver",app.driver);
            app.outputs = getAudioDevices(deviceWriter);
            app.OutputDeviceDropDown.Items = app.outputs;
            app.devicewriter = audioDeviceWriter("Driver",app.driver, ...
            "Device",app.OutputDeviceDropDown.Value, 'SampleRate',app.fs,'SupportVariableSizeInput',true);

            % Get sample Rate
            app.fs = get(app.devicewriter,'SampleRate');
            % Display sample rate
            app.SampleRateTextArea.Value = string(app.fs); 
        end

        % Value changed function: PowerOnSwitch
        function PowerOnSwitchValueChanged(app, event)
            value = app.PowerOnSwitch.Value;
            while value == "On"
                %% Read device input:
                app.input1 = app.deviceReader1() * app.volume/100;
%                 app.input2 = app.deviceReader2();
%                 app.input3 = app.deviceReader3();
                %% Filter Device:
                x = app.input1*app.Gain;


                xl = x(:,1);
                xr = x(:,2);
                %app.x_save = [app.x_save ;x];
                %% Filter Off
                if app.filters == "Off"
                   app.output = x;
                end
                %% LowPass Filter
                if app.filters == "LowPass"
                    app.output = process(app.LOWPASS,x);
                end
                %% HighPass Filter
                if app.filters == "HighPass"
                    app.output = process(app.HIGHPASS,x);
                end

                %% Notch Filter
                if app.filters == "Notch"
                    app.output = process(app.NOTCH,x);
                end

                %% Reverb Filter
                if app.filters == "Reverb"
                    app.output = process(app.ECHO,x);
                end
                
                %% Single Delay
                if app.filters == "Delay"
                    app.output = process(app.DELAY,x);
                end

                %% Phaser Filter (Currently example)
                if app.filters == "Phaser"
                    app.output = process(app.PHASER,x);
                end
                
                %% Flanger Filter (Currently example)
                if app. filters == "Flanger"
                    app.output = process(app.FLANGER,x);
                end

                %% Single Peaking Eq
                if app.filters == "Peaking EQ"
                    app.output = process(app.PEAKINGEQ,x);
                end
                
                %% Equalizer
                if app.filters == "Simple EQ"
                    app.output = process(app.EQ,x);
                end
               
                %% Write to audio Device:
                app.devicewriter(app.output);

                % Visual:
                step(app.Spectrum, app.output)

                plot(app.UIAxes, app.output);
                
                value = app.PowerOnSwitch.Value;
            end
                
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(app.devicewriter)
            delete(app.deviceReader1)
            delete(app.deviceReader2)
            delete(app.deviceReader3)
        end

        % Value changed function: OutputDeviceDropDown
        function OutputDeviceDropDownValueChanged(app, event)
            app.devicewriter = audioDeviceWriter("Driver",app.driver, ...
            "Device",app.OutputDeviceDropDown.Value, 'SampleRate',app.fs,'SupportVariableSizeInput',true);

            % Get sample Rate
            app.fs = get(app.devicewriter,'SampleRate');

            % Display sample rate
            app.SampleRateTextArea.Value = string(app.fs); 
        end

        % Value changed function: Device1DropDown
        function Device1DropDownValueChanged(app, event)
            app.deviceReader1 = audioDeviceReader("NumChannels", ...
            app.nChannels,"Driver",app.driver, ...
        'Device',app.Device1DropDown.Value, 'SampleRate',app.fs);
        end

        % Callback function
        function Device2DropDownValueChanged(app, event)
            app.deviceReader2 = audioDeviceReader("NumChannels", ...
            app.nChannels,"Driver",app.driver, ...
        'Device',app.Device2DropDown.Value, 'SampleRate',app.fs);
        end

        % Callback function
        function Device3DropDownValueChanged(app, event)
            app.deviceReader3 = audioDeviceReader("NumChannels", ...
            app.nChannels,"Driver",app.driver, ...
        'Device',app.Device3DropDown.Value, 'SampleRate',app.fs);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.volume = value;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.volume = changingValue;
        end

        % Value changed function: FiltersEffectsListBox
        function FiltersEffectsListBoxValueChanged(app, event)
            app.filters = app.FiltersEffectsListBox.Value;          
        end

        % Value changing function: Knob_4
        function Knob_4ValueChanging(app, event)
            app.ECHO.alpha = event.Value;
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
%             app.ECHO.Delay = app.Knob.Value;
%             
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            app.ECHO.Delay = event.Value;
            
        end

        % Value changing function: Knob_6
        function Knob_6ValueChanging(app, event)
            app.LOWPASS.Fc = event.Value;
            
        end

        % Value changing function: Knob_7
        function Knob_7ValueChanging(app, event)
            app.HIGHPASS.Fc = event.Value;
            
        end

        % Value changing function: Knob_9
        function Knob_9ValueChanging(app, event)
            app.DELAY.alpha = event.Value;
            
        end

        % Value changing function: Knob_8
        function Knob_8ValueChanging(app, event)
            app.DELAY.DelayNum = event.Value;
            
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            app.PEAKINGEQ.Fc = app.Knob_2.Value;
            
        end

        % Value changing function: Knob_3
        function Knob_3ValueChanging(app, event)
            app.PEAKINGEQ.A = event.Value;
            
        end

        % Value changing function: Knob_10
        function Knob_10ValueChanging(app, event)
            app.NOTCH.Fc = event.Value;
            
        end

        % Value changing function: InputGainKnob
        function InputGainKnobValueChanging(app, event)
            Gaindb = event.Value;
            app.Gain = 10^(Gaindb/20);
        end

        % Value changing function: LowCenterFrequencyKnob
        function LowCenterFrequencyKnobValueChanging(app, event)
            app.EQ.LowFc = event.Value;
            
        end

        % Value changing function: HighCenterFrequencyKnob
        function HighCenterFrequencyKnobValueChanging(app, event)
            app.EQ.HighFc = event.Value;
            
        end

        % Value changing function: MidCenterFrequencyKnob
        function MidCenterFrequencyKnobValueChanging(app, event)
            app.EQ.MidFc = event.Value;
            
        end

        % Value changing function: HighGainKnob
        function HighGainKnobValueChanging(app, event)
            app.EQ.HighGain = event.Value;
            
        end

        % Value changing function: MidGainKnob
        function MidGainKnobValueChanging(app, event)
            app.EQ.MidGain = event.Value;
            
        end

        % Value changing function: LowGainKnob
        function LowGainKnobValueChanging(app, event)
            app.EQ.LowGain = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Input Samples')
            xlabel(app.UIAxes, 'Samples (x)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.YLim = [0 0.2];
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 50;
            app.DriverDropDown = uidropdown(app.GridLayout);
            app.DriverDropDown.Items = {'ASIO', 'WASAPI', 'DirectSound'};
            app.DriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DriverDropDownValueChanged, true);
            app.DriverDropDown.Value = 'DirectSound';
            app.DeviceDropDown_3Label_2 = uilabel(app.GridLayout);
            app.DeviceDropDown_3Label_2.Text = 'Driver:';
            app.Knob_2 = uiknob(app.GridLayout, 'continuous');
            app.Knob_2.Limits = [0 20000];
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_3 = uiknob(app.GridLayout, 'continuous');
            app.Knob_3.Limits = [-64 64];
            app.Knob_3.ValueChangingFcn = createCallbackFcn(app, @Knob_3ValueChanging, true);
            app.Knob_3.Value = 20;
            app.Knob_4 = uiknob(app.GridLayout, 'continuous');
            app.Knob_4.Limits = [0 1];
            app.Knob_4.ValueChangingFcn = createCallbackFcn(app, @Knob_4ValueChanging, true);
            app.Knob_6 = uiknob(app.GridLayout, 'continuous');
            app.Knob_6.Limits = [0 20000];
            app.Knob_6.ValueChangingFcn = createCallbackFcn(app, @Knob_6ValueChanging, true);
            app.Knob_7 = uiknob(app.GridLayout, 'continuous');
            app.Knob_7.Limits = [0 20000];
            app.Knob_7.ValueChangingFcn = createCallbackFcn(app, @Knob_7ValueChanging, true);
            app.Knob_8 = uiknob(app.GridLayout, 'continuous');
            app.Knob_8.Limits = [0 1];
            app.Knob_8.ValueChangingFcn = createCallbackFcn(app, @Knob_8ValueChanging, true);
            app.Knob_9 = uiknob(app.GridLayout, 'continuous');
            app.Knob_9.Limits = [0 1];
            app.Knob_9.ValueChangingFcn = createCallbackFcn(app, @Knob_9ValueChanging, true);
            app.FiltersEffectsListBoxLabel = uilabel(app.GridLayout);
            app.FiltersEffectsListBoxLabel.Text = 'Filters/ Effects';
            app.FiltersEffectsListBox = uilistbox(app.GridLayout);
            app.FiltersEffectsListBox.Items = {'Off', 'HighPass', 'LowPass', 'Notch', 'Reverb', 'Delay', 'Phaser', 'Flanger', 'Peaking EQ', 'Simple EQ'};
            app.FiltersEffectsListBox.ValueChangedFcn = createCallbackFcn(app, @FiltersEffectsListBoxValueChanged, true);
            app.FiltersEffectsListBox.Value = 'Off';
            app.OutputDeviceDropDownLabel = uilabel(app.GridLayout);
            app.OutputDeviceDropDownLabel.Text = 'Output Device';
            app.OutputDeviceDropDown = uidropdown(app.GridLayout);
            app.OutputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDeviceDropDownValueChanged, true);
            app.PowerOnSwitchLabel = uilabel(app.GridLayout);
            app.PowerOnSwitchLabel.Text = 'Power On';
            app.PowerOnSwitch = uiswitch(app.GridLayout, 'rocker');
            app.PowerOnSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerOnSwitchValueChanged, true);
            app.Knob = uiknob(app.GridLayout, 'continuous');
            app.Knob.Limits = [0.1 1];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.Value = 0.5;
            app.SampleRateTextArea = uitextarea(app.GridLayout);
            app.ReverbDelayTimeLabel = uilabel(app.GridLayout);
            app.ReverbDelayTimeLabel.Text = 'Reverb Delay Time';
            app.SampleRateTextAreaLabel = uilabel(app.GridLayout);
            app.SampleRateTextAreaLabel.Text = 'Sample Rate:';
            app.ReverbAlphaLabel = uilabel(app.GridLayout);
            app.ReverbAlphaLabel.Text = 'Reverb Alpha';
            app.DelayTimeLabel = uilabel(app.GridLayout);
            app.DelayTimeLabel.Text = 'Delay Time';
            app.DelayAlphaLabel = uilabel(app.GridLayout);
            app.DelayAlphaLabel.Text = 'Delay Alpha';
            app.Device1DropDownLabel = uilabel(app.GridLayout);
            app.Device1DropDownLabel.Text = 'Device 1';
            app.Device1DropDown = uidropdown(app.GridLayout);
            app.Device1DropDown.ValueChangedFcn = createCallbackFcn(app, @Device1DropDownValueChanged, true);
            app.InputGainKnobLabel = uilabel(app.GridLayout);
            app.InputGainKnobLabel.Text = 'Input Gain';
            app.InputGainKnob = uiknob(app.GridLayout, 'continuous');
            app.InputGainKnob.Limits = [1 64];
            app.InputGainKnob.ValueChangingFcn = createCallbackFcn(app, @InputGainKnobValueChanging, true);
            app.InputGainKnob.Value = 1;
            app.Knob_10 = uiknob(app.GridLayout, 'continuous');
            app.Knob_10.Limits = [0 20000];
            app.Knob_10.ValueChangingFcn = createCallbackFcn(app, @Knob_10ValueChanging, true);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.LowPassFilterFcLabel = uilabel(app.GridLayout);
            app.LowPassFilterFcLabel.Text = 'Low Pass Filter Fc';
            app.HighPassFilterFcLabel = uilabel(app.GridLayout);
            app.HighPassFilterFcLabel.Text = 'High Pass Filter Fc';
            app.NotchMidFrequancyLabel = uilabel(app.GridLayout);
            app.NotchMidFrequancyLabel.Text = 'Notch Mid Frequancy';
            app.VolumeLabel = uilabel(app.GridLayout);
            app.VolumeLabel.Text = 'Volume';
            app.PeakingEQMidFrequancyLabel = uilabel(app.GridLayout);
            app.PeakingEQMidFrequancyLabel.Text = 'Peaking EQ Mid Frequancy';
            app.PeakingEQGainAttenuationLabel = uilabel(app.GridLayout);
            app.PeakingEQGainAttenuationLabel.Text = 'Peaking EQ Gain/Attenuation';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'All effects have their own specific paramater knobs that when adjusted will open the magnitude and phase response of the effect/filter. '; ''; 'The GUI Plot shows all input sample frames ploted in the size of the input frame. Default is 1024.'; ''; 'Note: Phaser and Flanger effects are built in MATLAB Audio Toolbox examples'; ''; 'Make sure to select the correct driver for your input and output devices. ASIO needs a supported ASIO device and drivers and must use the same input device as the output device.'; ''; 'Download "VB-Audio Cable" to be able to use the effects on other applications that use audio devices such as an online calling service or recording software https://vb-audio.com/Cable/'};
            app.MidCenterFrequencyKnobLabel = uilabel(app.GridLayout);
            app.MidCenterFrequencyKnobLabel.Text = 'Mid Center Frequency';
            app.MidCenterFrequencyKnob = uiknob(app.GridLayout, 'continuous');
            app.MidCenterFrequencyKnob.Limits = [600 5000];
            app.MidCenterFrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @MidCenterFrequencyKnobValueChanging, true);
            app.MidCenterFrequencyKnob.Value = 600;
            app.HighCenterFrequencyKnobLabel = uilabel(app.GridLayout);
            app.HighCenterFrequencyKnobLabel.Text = 'High Center Frequency';
            app.HighCenterFrequencyKnob = uiknob(app.GridLayout, 'continuous');
            app.HighCenterFrequencyKnob.Limits = [6000 15000];
            app.HighCenterFrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @HighCenterFrequencyKnobValueChanging, true);
            app.HighCenterFrequencyKnob.Value = 6000;
            app.LowCenterFrequencyKnobLabel = uilabel(app.GridLayout);
            app.LowCenterFrequencyKnobLabel.Text = 'Low Center Frequency';
            app.LowCenterFrequencyKnob = uiknob(app.GridLayout, 'continuous');
            app.LowCenterFrequencyKnob.Limits = [20 600];
            app.LowCenterFrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @LowCenterFrequencyKnobValueChanging, true);
            app.LowCenterFrequencyKnob.Value = 20;
            app.LowGainKnobLabel = uilabel(app.GridLayout);
            app.LowGainKnobLabel.Text = 'Low Gain';
            app.LowGainKnob = uiknob(app.GridLayout, 'continuous');
            app.LowGainKnob.Limits = [-64 64];
            app.LowGainKnob.ValueChangingFcn = createCallbackFcn(app, @LowGainKnobValueChanging, true);
            app.MidGainKnobLabel = uilabel(app.GridLayout);
            app.MidGainKnobLabel.Text = 'Mid Gain';
            app.MidGainKnob = uiknob(app.GridLayout, 'continuous');
            app.MidGainKnob.Limits = [-64 64];
            app.MidGainKnob.ValueChangingFcn = createCallbackFcn(app, @MidGainKnobValueChanging, true);
            app.HighGainKnobLabel = uilabel(app.GridLayout);
            app.HighGainKnobLabel.Text = 'High Gain';
            app.HighGainKnob = uiknob(app.GridLayout, 'continuous');
            app.HighGainKnob.Limits = [-64 64];
            app.HighGainKnob.ValueChangingFcn = createCallbackFcn(app, @HighGainKnobValueChanging, true);
            app.SimpleEqualizerLabel = uilabel(app.GridLayout);
            app.SimpleEqualizerLabel.Text = 'Simple Equalizer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MixerGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end